classdef LoadData_2DIRAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Load2DIRDataUIFigure       matlab.ui.Figure
        SpectrumPanel              matlab.ui.container.Panel
        SpectraOverviewPanel       matlab.ui.container.Panel
        SpectrumPlot               matlab.ui.control.UIAxes
        DataOptionsPanel           matlab.ui.container.Panel
        CalibrationPanel           matlab.ui.container.Panel
        InterceptSpinner           matlab.ui.control.Spinner
        InterceptSpinnerLabel      matlab.ui.control.Label
        SlopeSpinner               matlab.ui.control.Spinner
        SlopeSpinnerLabel          matlab.ui.control.Label
        DataRangesPanel            matlab.ui.container.Panel
        Label_3                    matlab.ui.control.Label
        w3maxRangeSpinner          matlab.ui.control.Spinner
        omega_32picmathrmcm1Label  matlab.ui.control.Label
        w3minRangeSpinner          matlab.ui.control.Spinner
        Label                      matlab.ui.control.Label
        w1maxRangeSpinner          matlab.ui.control.Spinner
        omega_12picmathrmcm1Label  matlab.ui.control.Label
        w1minRangeSpinner          matlab.ui.control.Spinner
        DataNameEditField          matlab.ui.control.EditField
        DataLabelEditFieldLabel    matlab.ui.control.Label
        DataPathLabel              matlab.ui.control.Label
        DisplayDataButton          matlab.ui.control.Button
        SelectDataButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        dataPath %Path to data
        dataFilenames
        
        dataName

        rawData
        sortedRawData

        croppedData
        w1_cropRange
        w3_cropRange

        calibratedData
        calibrationSlope = 1;
        calibrationIntercept = 0;

        overviewPlots
        
    end
    
    properties (Access = public)
        dataTable
    end

    methods (Access = private)
        
        function results = Load2DIRData(app)
%             try

                if ~iscell(app.dataFilenames)
                    temp = load(fullfile(app.dataPath, app.dataFilenames));
                    app.rawData = temp.data;
                else
                    temp = load(fullfile(app.dataPath, app.dataFilenames{1}));
                    app.rawData = temp.data;
                    for ii = 2:length(app.dataFilenames)
                        temp = load(fullfile(app.dataPath, app.dataFilenames{ii}));
                        app.rawData(ii) = temp.data;
                    end
                end
    
                t2s = [app.rawData(:).t2];
    
                [~,ind] = sort(t2s,'ascend');
                app.sortedRawData = app.rawData(ind);

                results = 1;
                
                if isempty(app.w1_cropRange)
                    app.w1_cropRange = [app.sortedRawData(1).w3(1) app.sortedRawData(1).w3(end)];
                    app.w1minRangeSpinner.Value = app.w1_cropRange(1);
                    app.w1maxRangeSpinner.Value = app.w1_cropRange(2);

                end
                if isempty(app.w3_cropRange)
                    app.w3_cropRange = app.w1_cropRange;

                    app.w3minRangeSpinner.Value = app.w3_cropRange(1);
                    app.w3maxRangeSpinner.Value = app.w3_cropRange(2);
                end
                
                app.cropData();
                
%             catch Err
%                 results = 0;
%             end
        end
        
        function results = update2DPlot(app)
            n_contours = 12;

            map=myMapRGB2(n_contours);

            MAX = max(abs(app.calibratedData(1).R(:)));

            level_list = linspace(-MAX,MAX,n_contours+2);
%             dl = level_list(2)-level_list(1); % I don't really understand why I have to do this shift
            level_list(1) = level_list(1);%-eps('single');
            level_list(end) = level_list(end);%+eps('single');
%             cmin =level_list(1)-dl/2;
%             cmax =level_list(end);%-dl/2+10*eps('single');

%             ca = [cmin cmax];


            [~, c] = contour(app.calibratedData(1).w1,...
                app.calibratedData(1).w3,app.calibratedData(1).R,level_list,...
                'Parent', app.SpectrumPlot);

            c.Fill = 'on';
            colormap(app.SpectrumPlot, map)

            line([app.calibratedData(1).w1(1) app.calibratedData(1).w1(end)], ...
                [app.calibratedData(1).w1(1) app.calibratedData(1).w1(end)],...
                'Color',[0 0 0], 'Parent', app.SpectrumPlot);

            app.SpectrumPlot.XLim = [app.calibratedData(1).w1(1) app.calibratedData(1).w1(end)];
            app.SpectrumPlot.YLim = [app.calibratedData(1).w3(1) app.calibratedData(1).w3(end)];
            app.SpectrumPlot.Title.String = sprintf('t_2 = %3.3g ps', app.calibratedData(1).t2./1000);

        end
        
        function results = cropData(app)
            
            app.croppedData = app.sortedRawData;

            for ii = 1:length(app.croppedData)
                app.croppedData(ii).R = app.croppedData(ii).R(...
                    app.croppedData(ii).w3 >= app.w3_cropRange(1) & app.croppedData(ii).w3 <= app.w3_cropRange(2),...
                    app.croppedData(ii).w1 >= app.w1_cropRange(1) & app.croppedData(ii).w1 <= app.w1_cropRange(2));

                app.croppedData(ii).w1 = app.croppedData(ii).w1(...
                    app.croppedData(ii).w1 >= app.w1_cropRange(1) & app.croppedData(ii).w1 <= app.w1_cropRange(2));

                app.croppedData(ii).w3 = app.croppedData(ii).w3(...
                    app.croppedData(ii).w3 >= app.w3_cropRange(1) & app.croppedData(ii).w3 <= app.w3_cropRange(2));
            end

        end
        
        function results = calibrate2DIRData(app)
            app.calibratedData = app.croppedData;
            for ii = 1:length(app.calibratedData)
                app.calibratedData(ii).w3 = app.calibrationSlope.*app.calibratedData(ii).w3 + app.calibrationIntercept;
            end
            app.w3maxRangeSpinner.Value = app.calibratedData(1).w3(end);
            app.w3minRangeSpinner.Value = app.calibratedData(1).w3(1);
        end
        
        function results = updateOverviewPlots(app)
            if isempty(app.overviewPlots)
                app.generateOverviewPlot();
            end


            for ii = 1:length(app.calibratedData)
                
                n_contours = 12;

                map=myMapRGB2(n_contours);

                MAX = max(abs(app.calibratedData(ii).R(:)));

                level_list = linspace(-MAX,MAX,n_contours+2);
                %             dl = level_list(2)-level_list(1); % I don't really understand why I have to do this shift
                level_list(1) = level_list(1);%-eps('single');
                level_list(end) = level_list(end);%+eps('single');
                %             cmin =level_list(1)-dl/2;
                %             cmax =level_list(end);%-dl/2+10*eps('single');

                %             ca = [cmin cmax];


                [~, c] = contour(app.calibratedData(ii).w1,app.calibratedData(ii).w3,app.calibratedData(ii).R,level_list, 'Parent', app.overviewPlots{ii});

                c.Fill = 'on';
                colormap(app.overviewPlots{ii}, map)

                line([app.calibratedData(ii).w1(1) app.calibratedData(ii).w1(end)],...
                    [app.calibratedData(ii).w1(1) app.calibratedData(ii).w1(end)],...
                    'Color',[0 0 0], 'Parent', app.overviewPlots{ii});

                app.overviewPlots{ii}.XLim = [app.calibratedData(1).w1(1) app.calibratedData(1).w1(end)];
                app.overviewPlots{ii}.YLim = [app.calibratedData(1).w3(1) app.calibratedData(1).w3(end)];
                app.overviewPlots{ii}.Title.String = sprintf('t_2 = %3.3g ps', app.calibratedData(ii).t2./1000);
                app.overviewPlots{ii}.PickableParts = 'none';
                
            end
            
        end
        
        function results = updateAllPlots(app)
            app.update2DPlot();
            app.updateOverviewPlots();
            
        end
        
        function results = generateOverviewPlot(app)
            if ~isempty(app.overviewPlots)
                for ii = 1:length(app.overviewPlots)
                    delete(app.overviewPlots{ii});
                end
            end

            plotWidth = 175;
            plotHeight = 175;
            spacing = 10;

            for ii = 1:length(app.calibratedData)
                app.overviewPlots{ii} = uiaxes(app.SpectraOverviewPanel,...
                        'Position', [spacing+((plotWidth+spacing).*(ii-1)),spacing, plotWidth, plotHeight], 'Box', 'on');
                app.overviewPlots{ii}.Toolbar.Visible = 'off';
                axis(app.overviewPlots{ii}, 'tight')
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            columnNames = {'Dir'; 'File Name'; 't2 (fs)'; 'Scans'; 'Keep'};
            pos = [5,416,400,370];
            app.dataTable = uitable_2DAnalyzer(app.DataOptionsPanel, 'ColumnName', columnNames, 'Position', pos);
            app.dataTable.UITable.ColumnEditable = [false false false false true];
            app.dataTable.UITable.ColumnSortable = true;
            app.dataTable.UITable.ColumnWidth = 'auto';
            app.dataTable.UITable.SelectionType = 'row';
%             app.dataTable.UITable.Scrolable = false;
            
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            figure(app.Load2DIRDataUIFigure)
            
            [app.dataFilenames, app.dataPath] = uigetfile('*.mat',...
                'Select One or More Data Files', ...
                'MultiSelect', 'on');
            
            figure(app.Load2DIRDataUIFigure)

            app.DataPathLabel.Text = sprintf('Data Path: %s', app.dataPath);

            app.Load2DIRData();
            
            d = cell(length(app.dataFilenames), 5);
%             d = cell(length(app.dataFilenames), 3);
            
            dataDir = split(app.dataPath, '\');
            if isempty(dataDir{end})
                dataDir = dataDir{end-1};
            else
                dataDir = dataDir{end};
            end

            if ~iscell(app.dataFilenames)
                d{1,1} = dataDir;
                d{1,2} = app.dataFilenames;
                d{1,3} = app.rawData.t2;
                d{1,4} = app.rawData.PARAMS.nScans;
                d{1,5} = true;

            else

                for ii = 1:length(app.dataFilenames)
                    d{ii,1} = dataDir;
                    d{ii,2} = app.dataFilenames{ii};
                    d{ii,3} = app.rawData(ii).t2;
                    d{ii,4} = app.rawData(ii).PARAMS.nScans;
                    d{ii,5} = true;
                end
            end


            d = cell2table(d);

%             app.dataTable.Data = d;
            app.dataTable.UITable.Data = d;


            app.calibrate2DIRData();

            app.generateOverviewPlot();

            app.updateAllPlots();

%             figure(app.Load2DIRDataUIFigure)

        end

        % Button pushed function: DisplayDataButton
        function DisplayDataButtonPushed(app, event)
            app.updateAllPlots();
        end

        % Value changed function: w1minRangeSpinner
        function w1minRangeSpinnerValueChanged(app, event)
            value = app.w1minRangeSpinner.Value;
            
            app.w1_cropRange = [value app.w1_cropRange(2)];

            app.cropData();
            app.calibrate2DIRData();
            app.updateAllPlots();
        end

        % Value changed function: w1maxRangeSpinner
        function w1maxRangeSpinnerValueChanged(app, event)
            value = app.w1maxRangeSpinner.Value;

            app.w1_cropRange = [app.w1_cropRange(1) value];

            app.cropData();
            app.calibrate2DIRData();
            app.updateAllPlots();
        end

        % Value changed function: w3minRangeSpinner
        function w3minRangeSpinnerValueChanged(app, event)
            value = app.w3minRangeSpinner.Value;
            
            app.w3_cropRange = [value app.w3_cropRange(2)];

            app.cropData();
            app.calibrate2DIRData();
            app.updateAllPlots();
        end

        % Value changed function: w3maxRangeSpinner
        function w3maxRangeSpinnerValueChanged(app, event)
            value = app.w3maxRangeSpinner.Value;
            
            app.w3_cropRange = [app.w3_cropRange(1) value];

            app.cropData();
            app.calibrate2DIRData();
            app.updateAllPlots();
        end

        % Value changed function: SlopeSpinner
        function SlopeSpinnerValueChanged(app, event)
            value = app.SlopeSpinner.Value;
            
            app.calibrationSlope = value;

            app.calibrate2DIRData();

            [m, indw3] = min(app.calibratedData(1).R);
            [~, indw1] = min(m);

            indw3 = indw3(indw1);

            w1 = app.calibratedData(1).w1(indw1);
            w3 = app.croppedData(1).w3(indw3);

            app.calibrationIntercept = (w1 - app.calibrationSlope.*w3);

            app.InterceptSpinner.Value = app.calibrationIntercept;

            app.calibrate2DIRData();

            
            app.updateAllPlots();
        end

        % Value changed function: InterceptSpinner
        function InterceptSpinnerValueChanged(app, event)
            value = app.InterceptSpinner.Value;
            
            app.calibrationIntercept = value;

            app.calibrate2DIRData();
            app.updateAllPlots();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Load2DIRDataUIFigure = uifigure('Visible', 'off');
            app.Load2DIRDataUIFigure.Name = 'Load 2D-IR Data';
            app.DataOptionsPanel = uipanel(app.Load2DIRDataUIFigure);
            app.DataOptionsPanel.Title = 'Data Options';
            app.SelectDataButton = uibutton(app.DataOptionsPanel, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.DisplayDataButton = uibutton(app.DataOptionsPanel, 'push');
            app.DisplayDataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayDataButtonPushed, true);
            app.DisplayDataButton.Text = 'Display Data';
            app.DataPathLabel = uilabel(app.DataOptionsPanel);
            app.DataPathLabel.Text = 'Data Path:';
            app.DataLabelEditFieldLabel = uilabel(app.DataOptionsPanel);
            app.DataLabelEditFieldLabel.Text = 'Data Name:';
            app.DataNameEditField = uieditfield(app.DataOptionsPanel, 'text');
            app.DataRangesPanel = uipanel(app.DataOptionsPanel);
            app.DataRangesPanel.Title = 'Data Ranges';
            app.w1minRangeSpinner = uispinner(app.DataRangesPanel);
            app.w1minRangeSpinner.ValueDisplayFormat = '%4.1f';
            app.w1minRangeSpinner.ValueChangedFcn = createCallbackFcn(app, @w1minRangeSpinnerValueChanged, true);
            app.omega_12picmathrmcm1Label = uilabel(app.DataRangesPanel);
            app.omega_12picmathrmcm1Label.Interpreter = 'latex';
            app.omega_12picmathrmcm1Label.Text = '$\omega_1/2\pi c \;(\mathrm{cm}^{-1})$:';
            app.w1maxRangeSpinner = uispinner(app.DataRangesPanel);
            app.w1maxRangeSpinner.ValueDisplayFormat = '%4.1f';
            app.w1maxRangeSpinner.ValueChangedFcn = createCallbackFcn(app, @w1maxRangeSpinnerValueChanged, true);
            app.Label = uilabel(app.DataRangesPanel);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '-';
            app.w3minRangeSpinner = uispinner(app.DataRangesPanel);
            app.w3minRangeSpinner.ValueDisplayFormat = '%4.1f';
            app.w3minRangeSpinner.ValueChangedFcn = createCallbackFcn(app, @w3minRangeSpinnerValueChanged, true);
            app.omega_32picmathrmcm1Label = uilabel(app.DataRangesPanel);
            app.omega_32picmathrmcm1Label.Interpreter = 'latex';
            app.omega_32picmathrmcm1Label.Text = '$\omega_3/2\pi c \;(\mathrm{cm}^{-1})$:';
            app.w3maxRangeSpinner = uispinner(app.DataRangesPanel);
            app.w3maxRangeSpinner.ValueDisplayFormat = '%4.1f';
            app.w3maxRangeSpinner.ValueChangedFcn = createCallbackFcn(app, @w3maxRangeSpinnerValueChanged, true);
            app.Label_3 = uilabel(app.DataRangesPanel);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '-';
            app.CalibrationPanel = uipanel(app.DataOptionsPanel);
            app.CalibrationPanel.Title = 'Calibration';
            app.SlopeSpinnerLabel = uilabel(app.CalibrationPanel);
            app.SlopeSpinnerLabel.Text = 'Slope:';
            app.SlopeSpinner = uispinner(app.CalibrationPanel);
            app.SlopeSpinner.Step = 0.005;
            app.SlopeSpinner.Limits = [1e-06 Inf];
            app.SlopeSpinner.ValueChangedFcn = createCallbackFcn(app, @SlopeSpinnerValueChanged, true);
            app.SlopeSpinner.Value = 1;
            app.InterceptSpinnerLabel = uilabel(app.CalibrationPanel);
            app.InterceptSpinnerLabel.Text = 'Intercept:';
            app.InterceptSpinner = uispinner(app.CalibrationPanel);
            app.InterceptSpinner.ValueChangedFcn = createCallbackFcn(app, @InterceptSpinnerValueChanged, true);
            app.SpectrumPanel = uipanel(app.Load2DIRDataUIFigure);
            app.SpectrumPlot = uiaxes(app.SpectrumPanel);
            xlabel(app.SpectrumPlot, '\omega_1/2\pic')
            ylabel(app.SpectrumPlot, '\omega_3/2\pic')
            app.SpectraOverviewPanel = uipanel(app.SpectrumPanel);
            app.SpectraOverviewPanel.Title = 'Spectra Overview';
            % Show the figure after all components are created
            app.Load2DIRDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadData_2DIRAnalyzer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Load2DIRDataUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.Load2DIRDataUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Load2DIRDataUIFigure)
        end
    end
end